classdef Assignment_7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ResultLabel                matlab.ui.control.Label
        SystemNumberLabel          matlab.ui.control.Label
        ScoreLabel                 matlab.ui.control.Label
        QuitButton                 matlab.ui.control.Button
        PlayButton                 matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        RoundsField                matlab.ui.control.NumericEditField
        NumberofRoundsLabel        matlab.ui.control.Label
        InputNumberField           matlab.ui.control.NumericEditField
        InputNumberEditFieldLabel  matlab.ui.control.Label
        LuckyRangeTheNumberChallengeLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        userScore = 0;      % Stores the user's score
        systemScore = 0;    % Stores the system's score
        totalRounds = 0;    % Total rounds for the game
        currentRound = 0;   % Tracks the current round
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Get total rounds from the UI and reset game state
            app.totalRounds = app.RoundsField.Value;
            app.userScore = 0;
            app.systemScore = 0;
            app.currentRound = 0;
        
            % Reset UI labels for the new game
            app.SystemNumberLabel.Text = "System: -";
            app.ResultLabel.Text = sprintf("Game Started! Total Rounds: %d", app.totalRounds);
            app.ScoreLabel.Text = "User: 0 | System: 0"
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % Check if the game is over
            if app.currentRound >= app.totalRounds
                app.ResultLabel.Text = "Game Over! Click Start to restart.";
                return;
            end
        
            % Get the user's guess from the input field
            userNum = app.InputNumberField.Value;
        
            % Generate the system's random number
            sysNum = randi([1, 50]);
            app.SystemNumberLabel.Text = sprintf("System: %d", sysNum);
        
            [cite_start]% Determine the round's result based on game rules [cite: 341-353]
            if userNum == sysNum
                app.userScore = app.userScore + 1;
                app.systemScore = app.systemScore + 1;
                result = "Tie! Both get 1 point.";
            elseif abs(userNum - sysNum) <= 5
                app.userScore = app.userScore + 1;
                result = "User wins this round!";
            else
                app.systemScore = app.systemScore + 1;
                result = "System wins this round!";
            end
        
            % Update the round counter and display the results
            app.currentRound = app.currentRound + 1;
            app.ResultLabel.Text = sprintf("%s (Round %d/%d)", result, app.currentRound, app.totalRounds);
            app.ScoreLabel.Text = sprintf("User: %d | System: %d", app.userScore, app.systemScore);
        
            [cite_start]% At the end of the last round, declare a winner [cite: 357-365]
            if app.currentRound == app.totalRounds
                if app.userScore > app.systemScore
                    winner = "User Wins!";
                elseif app.userScore < app.systemScore
                    winner = "System Wins!";
                else
                    winner = "It's a Tie!";
                end
                app.ResultLabel.Text = sprintf("Game Over! Final Result: %s", winner);
            end
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            % Display the final score if the user quits early
            app.ResultLabel.Text = sprintf("Game Quit! Final Score User: %d | System: %d", app.userScore, app.systemScore);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LuckyRangeTheNumberChallengeLabel = uilabel(app.UIFigure);
            app.LuckyRangeTheNumberChallengeLabel.Text = 'Lucky Range: The Number Challenge';
            app.InputNumberEditFieldLabel = uilabel(app.UIFigure);
            app.InputNumberEditFieldLabel.Text = 'Input Number';
            app.InputNumberField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofRoundsLabel = uilabel(app.UIFigure);
            app.NumberofRoundsLabel.Text = 'Number of Rounds';
            app.RoundsField = uieditfield(app.UIFigure, 'numeric');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play Round';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.ScoreLabel = uilabel(app.UIFigure);
            app.ScoreLabel.Text = 'User: 0 | System: 0';
            app.SystemNumberLabel = uilabel(app.UIFigure);
            app.SystemNumberLabel.Text = 'System: -';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result will appear here';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Assignment_7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end